classdef cap1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_2                      matlab.ui.control.Label
        EpochEditField               matlab.ui.control.NumericEditField
        RatioEditField               matlab.ui.control.NumericEditField
        Label_3                      matlab.ui.control.Label
        RecognizingButton            matlab.ui.control.Button
        EditField                    matlab.ui.control.NumericEditField
        ChannelselectionButtonGroup  matlab.ui.container.ButtonGroup
        CH6Button                    matlab.ui.control.RadioButton
        CH5Button                    matlab.ui.control.RadioButton
        CH4Button                    matlab.ui.control.RadioButton
        CH3Button                    matlab.ui.control.RadioButton
        CH2Button                    matlab.ui.control.RadioButton
        CH1Button                    matlab.ui.control.RadioButton
        TrainingButton               matlab.ui.control.Button
        SavinginformationButton      matlab.ui.control.Button
        CollectinginformationButton  matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CollectinginformationButton
        function CollectinginformationButtonPushed(app, event)
           plot(app.UIAxes,0)
            [cap1to6,cap_lv_cap1to6]=read_serial4();
            cap_lv_1to6=cap_lv_cap1to6';
            plot(app.UIAxes,cap_lv_1to6,LineWidth=3)

            cap1=cap1to6(1,:);
            cap_lv_1=cap_lv_cap1to6(1,:);
            plot(app.UIAxes2,cap1,LineWidth=3)
            plot(app.UIAxes3,cap_lv_1,LineWidth=3)
            
        end

        % Button pushed function: SavinginformationButton
        function SavinginformationButtonPushed(app, event)
            cap_num=save_read_serial();
%             cap_num_num=12
            app.EditField.Value= cap_num;
%             app.EditField_4.Value=mod(cap_num,26);
%             app.TextArea.Value=num2str(cap_num);

        end

        % Button down function: ChannelselectionButtonGroup
        function ChannelselectionButtonGroupButtonDown(app, event)
            
        end

        % Selection changed function: ChannelselectionButtonGroup
        function ChannelselectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChannelselectionButtonGroup.SelectedObject;
%             [cap1to6,cap_lv_cap1to6]=read_serial4();
            load cap_data_cap1to6.mat
            switch selectedButton.Text
                case 'CH1'
                    cap1=cap1to6(1,:);
                    cap_lv_1=cap_lv_cap1to6(1,:);
                    plot(app.UIAxes2,cap1)
                    plot(app.UIAxes3,cap_lv_1,LineWidth=3)
                case 'CH2'
                    cap1=cap1to6(2,:);
                    cap_lv_1=cap_lv_cap1to6(2,:);
                    plot(app.UIAxes2,cap1)
                    plot(app.UIAxes3,cap_lv_1,LineWidth=3)
                case 'CH3'
                    cap1=cap1to6(3,:);
                    cap_lv_1=cap_lv_cap1to6(3,:);
                    plot(app.UIAxes2,cap1)
                    plot(app.UIAxes3,cap_lv_1,LineWidth=3)

                case 'CH4'
                    cap1=cap1to6(4,:);
                    cap_lv_1=cap_lv_cap1to6(4,:);
                    plot(app.UIAxes2,cap1)
                    plot(app.UIAxes3,cap_lv_1,LineWidth=3)
                case 'CH5'
                    cap1=cap1to6(5,:);
                    cap_lv_1=cap_lv_cap1to6(5,:);
                    plot(app.UIAxes2,cap1)
                    plot(app.UIAxes3,cap_lv_1,LineWidth=3)                 
                case 'CH6'
                    cap1=cap1to6(6,:);
                    cap_lv_1=cap_lv_cap1to6(6,:);
                    plot(app.UIAxes2,cap1)
                    plot(app.UIAxes3,cap_lv_1,LineWidth=3)
            end
        end

        % Button pushed function: RecognizingButton
        function RecognizingButtonPushed(app, event)
            delete(app.UIFigure)
            run cap.mlapp
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
            ratio1=app.RatioEditField.Value
            data_label(ratio1);
            cnn(app.EpochEditField.Value);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: RatioEditField
        function RatioEditFieldValueChanged(app, event)
            value = app.RatioEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Cap(pF)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Inputing data')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Cap(pF)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Processing data')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Cap(pF)')
            zlabel(app.UIAxes3, 'Z')
            app.CollectinginformationButton = uibutton(app.UIFigure, 'push');
            app.CollectinginformationButton.ButtonPushedFcn = createCallbackFcn(app, @CollectinginformationButtonPushed, true);
            app.CollectinginformationButton.Text = 'Collecting information';
            app.SavinginformationButton = uibutton(app.UIFigure, 'push');
            app.SavinginformationButton.ButtonPushedFcn = createCallbackFcn(app, @SavinginformationButtonPushed, true);
            app.SavinginformationButton.Text = 'Saving information';
            app.TrainingButton = uibutton(app.UIFigure, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.ChannelselectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ChannelselectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelselectionButtonGroupSelectionChanged, true);
            app.ChannelselectionButtonGroup.Title = 'Channel selection';
            app.ChannelselectionButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ChannelselectionButtonGroupButtonDown, true);
            app.CH1Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.CH1Button.Text = 'CH1';
            app.CH1Button.Value = true;
            app.CH2Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.CH2Button.Text = 'CH2';
            app.CH3Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.CH3Button.Text = 'CH3';
            app.CH4Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.CH4Button.Text = 'CH4';
            app.CH5Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.CH5Button.Text = 'CH5';
            app.CH6Button = uiradiobutton(app.ChannelselectionButtonGroup);
            app.CH6Button.Text = 'CH6';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.RecognizingButton = uibutton(app.UIFigure, 'push');
            app.RecognizingButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizingButtonPushed, true);
            app.RecognizingButton.Text = 'Recognizing';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.RatioEditField.ValueChangedFcn = createCallbackFcn(app, @RatioEditFieldValueChanged, true);
            app.RatioEditField.Value = 0.6;
            app.EpochEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpochEditField.Value = 1300;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Epoch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cap1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end