classdef cap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        RecognizingButton       matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        StartingsamplingButton  matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartingsamplingButton
        function StartingsamplingButtonPushed(app, event)
%                 [X Y] = read_serial3();
%                 plot(app.UIAxes,X,Y);
%                 axis(app.UIAxes,[0 1000 0 300])
%             plot(app.UIAxes,0)
%             app.ResultEditField.Value='wait';
%             clear(app.ResultEditField.Value);
            img=imread('0.png');
             app.Image.ImageSource=img;
            [cap1to6,cap_lv_cap1to6]=read_serial4();
            cap_lv_1to6=cap_lv_cap1to6';
            plot(app.UIAxes,cap_lv_1to6,LineWidth=3)
        end

        % Button pushed function: RecognizingButton
        function RecognizingButtonPushed(app, event)
           value=num2str(classfy());
%             value=4;
             plot(app.UIAxes,0)
            file=strcat('C:\Users\cab\Documents\MATLAB\cap\cap_sys2\Figure\2\',num2str(value),'.jpg');
            img=imread(file);
            app.Image.ImageSource=img;

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
%             value = num2str(classfy());
%             value=app.EditField.Value;
%             file=strcat('C:\Users\cab\Documents\MATLAB\cap\cap_sys2\Figure\1\',num2str(value),'.png');
%             img=imread(file);
%             app.Image.ImageSource=img;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Cap (pF)')
            zlabel(app.UIAxes, 'Z')
            app.StartingsamplingButton = uibutton(app.UIFigure, 'push');
            app.StartingsamplingButton.ButtonPushedFcn = createCallbackFcn(app, @StartingsamplingButtonPushed, true);
            app.StartingsamplingButton.Text = 'Starting sampling';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Recognizing'};
            app.RecognizingButton = uibutton(app.UIFigure, 'push');
            app.RecognizingButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizingButtonPushed, true);
            app.RecognizingButton.Text = 'Recognizing';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end